classdef AppIntruderDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TakeReferenceButton   matlab.ui.control.Button
        ThresholdKnobLabel    matlab.ui.control.Label
        ThresholdKnob         matlab.ui.control.Knob
        MinimunSizeKnobLabel  matlab.ui.control.Label
        MinimunSizeKnob       matlab.ui.control.Knob
        IntruderLamp          matlab.ui.control.Lamp
        MaximumSizeKnobLabel  matlab.ui.control.Label
        MaximumSizeKnob       matlab.ui.control.Knob
        IntruderLabel         matlab.ui.control.Label
        AllClearLampLabel     matlab.ui.control.Label
        AllClearLamp          matlab.ui.control.Lamp
        Switch                matlab.ui.control.Switch
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_SC             matlab.ui.control.UIAxes
        UIAxes_TH             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img; % Reference Image
        vid_img; % Updated images
        hShow; % Matrix for img
        tShow; % Matrix for threshold
        camera;
        object_detected; % Overlayed fig
        alert; % boolean value
        imgthr; % Threshold img
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.camera = webcam('EpocCam Camera');
            app.hShow = imshow(zeros(960,720,3,'uint8'), 'Parent', app.UIAxes_SC);
            app.tShow = imshow(zeros(960,720), 'Parent', app.UIAxes_TH);
            app.IntruderLamp.Color = [0.5 0.5 0.5];
            app.AllClearLamp.Color = [0 1 0];
        end

        % Button pushed function: TakeReferenceButton
        function TakeReferenceButtonPushed(app, event)
            app.img = snapshot(app.camera);
            imshow(app.img, 'Parent', app.UIAxes);
                     
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            while strcmpi(app.Switch.Value, 'On')
                % Acquire an image from the cam
                app.vid_img = snapshot(app.camera);
                threshold = app.ThresholdKnob.Value;
                min_size = app.MinimunSizeKnob.Value;
                max_size = app.MaximumSizeKnob.Value;
                % Call the live segmentation function
                [app.object_detected, app.alert, app.imgthr] = Segmentation_Fn_App(app.vid_img, app.img, threshold, min_size, max_size);%objThresh, objSize
                set(app.hShow, 'CData', app.object_detected);
                drawnow;
                set(app.tShow, 'CData', app.imgthr);
                drawnow;
                if app.alert
                    app.IntruderLamp.Color = [1 0 0];
                    app.AllClearLamp.Color = [0.5 0.5 0.5];
                else
                    app.IntruderLamp.Color = [0.5 0.5 0.5];
                    app.AllClearLamp.Color = [0 1 0];
                end

            clear app.camera;

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TakeReferenceButton = uibutton(app.UIFigure, 'push');
            app.TakeReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @TakeReferenceButtonPushed, true);
            app.TakeReferenceButton.Text = 'Take Reference';
            app.ThresholdKnobLabel = uilabel(app.UIFigure);
            app.ThresholdKnobLabel.Text = 'Threshold';
            app.ThresholdKnob = uiknob(app.UIFigure, 'continuous');
            app.MinimunSizeKnobLabel = uilabel(app.UIFigure);
            app.MinimunSizeKnobLabel.Text = 'Minimun Size';
            app.MinimunSizeKnob = uiknob(app.UIFigure, 'continuous');
            app.MinimunSizeKnob.Limits = [0 200];
            app.IntruderLamp = uilamp(app.UIFigure);
            app.MaximumSizeKnobLabel = uilabel(app.UIFigure);
            app.MaximumSizeKnobLabel.Text = 'Maximum Size';
            app.MaximumSizeKnob = uiknob(app.UIFigure, 'continuous');
            app.MaximumSizeKnob.Limits = [0 200];
            app.IntruderLabel = uilabel(app.UIFigure);
            app.IntruderLabel.Text = 'Intruder';
            app.AllClearLampLabel = uilabel(app.UIFigure);
            app.AllClearLampLabel.Text = 'All Clear';
            app.AllClearLamp = uilamp(app.UIFigure);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Reference')
            app.UIAxes_SC = uiaxes(app.UIFigure);
            title(app.UIAxes_SC, 'Security Cam')
            app.UIAxes_TH = uiaxes(app.UIFigure);
            title(app.UIAxes_TH, 'Threshold')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppIntruderDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end